classdef App2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure         % UI Figure
        LabelSlider  matlab.ui.control.Label  % Slider
        FunForma1    matlab.ui.control.Slider % [0 100]
        LabelSlider2 matlab.ui.control.Label  % Slider
        FunForma2    matlab.ui.control.Slider % [0 100]
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % FunForma1 value changing function
        function FunForma1ValueChanging(app, event)
            changingValue = event.Value;
            x=app.FunForma1.Value
            y=app.FunForma1.Value
            A=1/4*(1-x)*(1-y)
            B=1/4*(1+x)*(1-y)
            C=1/4*(1+x)*(1+y)
            D=1/4*(1-x)*(1+y)
        end

        % FunForma2 value changing function
        function FunForma2ValueChanging(app, event)
            changingValue = event.Value;
            x=app.FunForma1.Value
            y=app.FunForma1.Value
            A=1/4*(1-x)*(1-y)
            B=1/4*(1+x)*(1-y)
            C=1/4*(1+x)*(1+y)
            D=1/4*(1-x)*(1+y)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelSlider = uilabel(app.UIFigure);
            app.LabelSlider.Text = 'Slider';
            app.FunForma1 = uislider(app.UIFigure);
            app.FunForma1.ValueChangingFcn = createCallbackFcn(app, @FunForma1ValueChanging, true);
            app.LabelSlider2 = uilabel(app.UIFigure);
            app.LabelSlider2.Text = 'Slider';
            app.FunForma2 = uislider(app.UIFigure);
            app.FunForma2.ValueChangingFcn = createCallbackFcn(app, @FunForma2ValueChanging, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = App2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end