classdef KGDApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % KDGApp
        StartKGD               matlab.ui.control.Button           % Start K...
        LabelKnob              matlab.ui.control.Label            % Log(T/T*)
        Time                   matlab.ui.control.Knob             % [-5 4]
        LabelNumericEditField  matlab.ui.control.Label            % Q0 [(m^...
        Q0                     matlab.ui.control.NumericEditField % [0 Inf]
        MultiplePlots          matlab.ui.control.StateButton      % Multipl...
        LabelNumericEditField2 matlab.ui.control.Label            % E  [Pa]
        E                      matlab.ui.control.NumericEditField % [0 Inf]
        Label                  matlab.ui.control.Label            % KIc  [Pa]
        KIc                    matlab.ui.control.NumericEditField % [0 Inf]
        Label2                 matlab.ui.control.Label            % mu [Pa s]
        mu                     matlab.ui.control.NumericEditField % [0 Inf]
        Label3                 matlab.ui.control.Label            % v
        v                      matlab.ui.control.NumericEditField % [0 1]
        Label4                 matlab.ui.control.Label            % Cl [m/(...
        Cl                     matlab.ui.control.NumericEditField % [0 Inf]
        Comparador             matlab.ui.control.StateButton      % Compare...
        Label6                 matlab.ui.control.Label            % KGD APP...
        LabelDiscreteKnob      matlab.ui.control.Label            % Model
        Modelo                 matlab.ui.control.DiscreteKnob     %    M, M...
        Panel                  matlab.ui.container.Panel         
        TwoD                   matlab.ui.control.StateButton      % 2D View
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % StartKGD button pushed function
        function StartKGDButtonPushed(app)
            figure(1)
            hold off
            clf


%% Imput data Original

E = 28.125e9;% Young modulus [Pa]

KIc = 1e6; % Rock toughness [Pa]

mu = 1/12; % Viscosity [Pa s]

Cl = 0.5e-5; % Lumped leak-off coefficient [m/(s^0.5)]

v = 0.25; % Poisson

Q0 = 1e-3; % Injection Rate [(m^2)/s]

%% Imput data

E = app.E.Value;% Young modulus [Pa]

KIc = app.KIc.Value; % Rock toughness [Pa]

mu = app.mu.Value; % Viscosity [Pa s]

Cl = app.Cl.Value; % Lumped leak-off coefficient [m/(s^0.5)]

v = app.v.Value; % Poisson

Q0 = app.Q0.Value; % Injection Rate [(m^2)/s]

%% Small post-process

Eprime = E/(1-(v^2));

Kprime = 4*((2/pi)^0.5)*KIc;

muprime = 12*mu;

Cprime = 2*Cl;

%% * Coeffcients

t_ = (muprime*(Q0^3))/(Eprime*(Cprime^6));

l_ = ((muprime*(Q0^5))/(Eprime*(Cprime^8)))^0.5;

p_ = (Eprime*(Cprime^2))/Q0;

t = [t_*logspace(-6, app.Time.Value, 100)]';

%% Scaling 

% M Scaling------------

epsilonM = (muprime./(Eprime.*t)).^(1/3);

LM = (((Eprime*(Q0^3))/muprime)^(1/6))*(t.^(2/3));

Km = (Kprime/Eprime)*((Eprime/(muprime*Q0))^(1/4));

Cm = (t/t_).^(1/6);

% K Scaling------------

epsilonK = ((Kprime^4)./((Eprime^4)*Q0*t)).^(1/3);

LK = LM;

Mk = Km^-4;

Ck = (Km^(-2/3))*Cm;

% Mbow Scaling --------

epsilonMbow = (((Cprime^2)*muprime)./(Eprime*Q0*t)).^0.25;

LMbow = (Q0/Cprime)*(t.^0.5);

Kmbow = Km;

Smbow = Cm.^(-3/2);

% Mbow Scaling --------

epsilonKbow = (((Kprime^4)*(Cprime^2))./((Eprime^4)*(Q0^2)*t)).^0.25;

LKbow = LMbow;

Mkbow = Mk;

Skbow = Ck.^(-3/2);

%% Coefficients S, C, M, and K
if app.Modelo.Value=='   M'
    
    epsilon = epsilonM;

    L = LM;
    
    multiplicarPi=1;
    
    multiplicarOmega=1;
    
    multiplicarGama=1;
    
elseif app.Modelo.Value=='Mbow'
    
    epsilon = epsilonMbow;

    L = LMbow;
    
    multiplicarPi=Cm.^(-0.5);% Eq 24
    
    multiplicarOmega(1,1,:)=Cm.^(0.5)% Eq 24
    
    multiplicarGama=Cm;% Eq 24
    
elseif app.Modelo.Value=='Kbow'
    
    epsilon = epsilonKbow;

    L = LKbow;
    
    multiplicarPi=1./((Cm.^(0.5)).*Km);% Eq 24
    
    multiplicarOmega(1,1,:)=1./((Cm.^(-0.5)).*Km);% Eq 24
    
    multiplicarGama=Cm;% Eq 24
    
elseif app.Modelo.Value=='   K'
    
    epsilon = epsilonK;

    L = LK;
    
    multiplicarPi=Km.^(-(4/3));% Eq 24
    
    multiplicarOmega(1,1,:)=Km.^(-(2/3));% Eq 24
    
    multiplicarGama=Km.^(-(-2/3));% Eq 24
    
end


epsilon = epsilonM;

L = LM;

S = (epsilon.*(L.^2))./(Q0*t);

C = (Cprime*L)./(Q0*(t.^0.5));

M = (Q0*muprime)./((epsilon.^4).*(L.^2)*Eprime);

K = Kprime./(epsilon*Eprime.*(L.^0.5));

%% Import data for gama and PI

gamam=importdata('gamam.mat');
gamam=[0 gamam(1,2)
       gamam(:,1) gamam(:,2)
       gamam(end,1)*100 gamam(end)];

Gama_m=interp1(gamam(:,1), gamam(:,2),Cm, 'pchip');
   
lm=LM.*Gama_m;

Gama_m_bow=Gama_m.*Cm;

lmbow=LMbow.*Gama_m_bow;

%% Import data for PI 0

pi0=importdata('Pi0.mat');

pi0=[0 pi0(1,2)
     pi0(:,1) pi0(:,2)
     pi0(end,1)*100 pi0(end)];

Pi0=interp1(pi0(:,1), pi0(:,2),Cm, 'pchip');

P=epsilonM.*Eprime.*Pi0;


%% Omega

omegam=importdata('omegam.mat');

omegam_m_Cm0=omegam(:,[1 2],1);

omegam_m_Cm0=[0 omegam_m_Cm0(1,2)
       omegam_m_Cm0(:,1) omegam_m_Cm0(:,2)
       omegam_m_Cm0(end,1)*100 omegam_m_Cm0(end)];
   
omegam_m_Cm01=omegam(:,[1 2],2);

omegam_m_Cm01=[0 omegam_m_Cm01(1,2)
       omegam_m_Cm01(:,1) omegam_m_Cm01(:,2)
       omegam_m_Cm01(end,1)*100 omegam_m_Cm01(end)];

omegam_m_Cm464=omegam(:,[1 2],3);

omegam_m_Cm464=[0 omegam_m_Cm464(1,2)
       omegam_m_Cm464(:,1) omegam_m_Cm464(:,2)
       omegam_m_Cm464(end,1)*100 omegam_m_Cm464(end)];
   
   
Omegam(:,:,1)= omegam_m_Cm0;
   
Omegam(:,:,2)= omegam_m_Cm01;
   
Omegam(:,:,3)= omegam_m_Cm464;

C__m=[0 0.1 4.64];

OMEGAM=zeros(length(Omegam(:,1,1)),2,length(Cm));

for i=1:length(Omegam(:,1,1))
    aux(:,1,1)=Omegam(i,2,:);
    OMEGAM(i,2,:)=interp1(C__m, aux,Cm, 'pchip');
    OMEGAM(i,2,:)=OMEGAM(i,2,:).*multiplicarOmega;
    OMEGAM(i,1,:)=Omegam(i,1,1);
end

for i=1:length(Cm)
    wM(:,i)=epsilonM(i)*LM(i)*OMEGAM([2:end-1],2,i);
end


%% Plots

closear=[2:9];

if    app.Comparador.Value ==false

for i=1

C_m=Cm(i);
   
time=(C_m^6)*t_;
   
epsilonm = (muprime./(Eprime.* time)).^(1/3);

Lm = (((Eprime*(Q0^3))/muprime)^(1/6))*( time.^(2/3));
   
wm=epsilonm*Lm*OMEGAM([2:end-1],2,i);

figure(1)

hold on

subplot(2,2,3)

plot(0:1/100:1, wm)
if app.MultiplePlots.Value == true

figure(2)

hold on

plot(0:1/100:1, wm)
end

end

for f=1:2
    
    if f>1
        if app.MultiplePlots.Value==false
            break
        end
    end
    
    figure(f)

set(gcf,'color','w');

set(gca, 'FontSize' , 20)

grid on

xlabel('\it\fontname{Times New Roman} \xi =x/l','fontsize',23)

ylabel('\it\fontname{Times New Roman} \omega [m]','fontsize',23)

title(' Fracture aperture','fontsize',23)

legend(['t : ' num2str(round(time/3600,1)) 'hrs'],'Location','southwest')

end
%-------------

for f=[1 3]
    if f>1
        if app.MultiplePlots.Value==false
            break
        end
    end

figure(f)

if f==1
    subplot(2,2,1)
end

[X,Y]=meshgrid(Cm.^6,0:1/100:1);

for i=1:length(Cm)
    Z(:,i)=wM(:,i);
end
s = surf(X,Y,Z,'FaceAlpha',0.9)
s.EdgeColor = 'none';


set(gcf,'color','w');

set(gca, 'FontSize' , 20)

set(gca, 'XScale', 'log')

zlim([0 max([max(Z(1,:))  ])])

grid on

xlabel('\it\fontname{Times New Roman} t/t_*','fontsize',23)

ylabel('\it\fontname{Times New Roman} \xi =x/l','fontsize',23)

zlabel('\it\fontname{Times New Roman} \omega [m]','fontsize',23)

title(' Fracture aperture','fontsize',23)

end

%-------------
for f=[1 4]
    if f>1
        if app.MultiplePlots.Value==false
            break
        end
    end
figure(f)
if f==1
    subplot(2,2,2)
end
[X,Y]=meshgrid(t/3600,eps:1/100:1);

for i=1:length(Cm)
    Z(:,i)=wM(:,i);
    Y(:,i)=Y(:,i)*lm(i);
    
    
end


s = surf(X,Y,Z,'FaceAlpha',0.9)
s.EdgeColor = 'none';

if app.Comparador.Value==true
    
    dataToCompare=importdata('AperturaFisura.mat')
    dataToCompare(:,1)=dataToCompare(:,1)*((max(max(X)))/(max(dataToCompare(:,1))));
    hold on

    plot3(dataToCompare(:,1),dataToCompare(:,1)*0,dataToCompare(:,2),'b','Linewidth',2)
    plot3(X(1,:),Y(1,:),Z(1,:),'r','Linewidth',2)
%     xlim([min(X(1,:)) 1.1*max(X(1,:))])
end


set(gcf,'color','w');

set(gca, 'FontSize' , 20)
% 
% set(gca, 'XScale', 'log')
% 
% set(gca, 'YScale', 'log')
% 
% ylim([1e-3 max(max(lm))])

zlim([0 max([max(Z(1,:))  ])])

grid on

xlabel('\it\fontname{Times New Roman} t [h]','fontsize',23)

ylabel('\it\fontname{Times New Roman} l [m]','fontsize',23)

zlabel('\it\fontname{Times New Roman} \omega [m]','fontsize',23)

title(' Fracture aperture','fontsize',23)

end
%------------
% % 
% % for f=[1 5]
% %     if f>1
% %         if app.MultiplePlots.Value==false
% %             break
% %         end
% %     end
% % 
% % figure(f)
% % 
% % if f==1
% %     subplot(2,4,4)
% % end
% % 
% % ax=loglog(Cm.^6,LM./l_,'b')
% % 
% % set(gcf,'color','w');
% % 
% % set(gca, 'FontSize' , 20)
% % 
% % grid on
% % 
% % xlabel('\it\fontname{Times New Roman} t/t_*','fontsize',23)
% % 
% % ylabel('\it\fontname{Times New Roman} L/l_*','fontsize',23)
% % 
% % title(' Half-length evolution','fontsize',23)
% % 
% % hold on
% % 
% % loglog(Cm.^6,LMbow./l_,'r')
% % 
% % legend('LM', 'LM˜','Location','northwest')
% % end
% % 
% % %-------------
% % for f=[1 6]
% %     
% %     if f>1
% %         if app.MultiplePlots.Value==false
% %             break
% %         end
% %     end
% % 
% % figure(f)
% % 
% % if f==1
% %     subplot(2,4,5)
% % end
% % 
% % ax=loglog(Cm.^6,lm./l_,'b')
% % 
% % hold on
% % 
% % loglog(Cm.^6,lmbow./l_,'r-.')
% % 
% % set(gcf,'color','w');
% % 
% % set(gca, 'FontSize' , 20)
% % 
% % grid on
% % 
% % xlabel('\it\fontname{Times New Roman} t/t_*','fontsize',23)
% % 
% % ylabel('\it\fontname{Times New Roman} l/l_*','fontsize',23)
% % 
% % title(' Half-length evolution','fontsize',23)
% % 
% % legend('l_m  K_m =1', 'l_m_˜  K_m =1','Location','northwest')
% % end
% % 
% % %---------------
% % for f=[1 7]
% %     app.MultiplePlots.Value
% %     
% %     if f>1
% %         if app.MultiplePlots.Value==false
% %             break
% %         end
% %     end
% % 
% % figure(f)
% % 
% % if f==1
% %     subplot(2,4,6)
% % end
% % 
% % ax=loglog(t/3600,lm,'b')
% % 
% % hold on
% % 
% % loglog(t/3600,lmbow,'r-.')
% % 
% % set(gcf,'color','w');
% % 
% % set(gca, 'FontSize' , 20)
% % 
% % grid on
% % 
% % xlabel('\it\fontname{Times New Roman} t [h]','fontsize',23)
% % 
% % ylabel('\it\fontname{Times New Roman} l [m]','fontsize',23)
% % 
% % title(' Half-length evolution','fontsize',23)
% % 
% % legend('l_m  K_m =1', 'l_m_˜  K_m =1','Location','northwest')
% % 
% % drawnow
% % clc
% % 
% % disp(' ')
% % 
% % disp(['Largo de fisura: ' num2str(round(lm(15))) ' metros'])
% % 
% % disp(' ')
% % 
% % disp(['Ancho inicial de fisura: ' num2str(round(max(wM(:,1)),3)) ' metros'])
% % 
% % disp(' ')
% % 
% % disp(['Ancho final de fisura: ' num2str(round(max(wM(:,15)),3)) ' metros'])
% % 
% % disp(' ')
% % 
% % disp(['Tiempo inicial: ' num2str(round(max(t(1)/3600))) ' horas'])
% % 
% % disp(' ')
% % 
% % disp(['Tiempo final: ' num2str(round(max(t(15)/3600))) ' horas'])
% % 
% % end
% % 
% % %---------------
% % for f=[1 8]
% %     
% %     if f>1
% %         if app.MultiplePlots.Value==false
% %             break
% %         end
% %     end
% % 
% % figure(f)
% % 
% % if f==1
% %     subplot(2,4,7)
% % end
% % 
% % ax=loglog(Cm.^6,P./p_,'b');
% % 
% % set(gcf,'color','w');
% % 
% % set(gca, 'FontSize' , 20)
% % 
% % grid on
% % 
% % xlabel('\it\fontname{Times New Roman} t/t_*','fontsize',23)
% % 
% % ylabel('\it\fontname{Times New Roman} p(0)/p_*','fontsize',23)
% % 
% % title(' Inlet net-fluid pressure','fontsize',23)
% % 
% % % legend('l_m  K_m =1', 'l_m_˜  K_m =1','Location','northwest')
% % end

%---------------
for f=[1 9]
    
    if f>1
        if app.MultiplePlots.Value==false
            break
        end
    end

figure(f)

if f==1
    subplot(2,2,4)
end

ax=semilogx(t/3600,P/1e6,'b');

set(gcf,'color','w');

set(gca, 'FontSize' , 20)

grid on

xlabel('\it\fontname{Times New Roman} t  [h]','fontsize',23)

ylabel('\it\fontname{Times New Roman} p(0)  [MPa]','fontsize',23)

title(' Inlet net-fluid pressure','fontsize',23)

% legend('l_m  K_m =1', 'l_m_˜  K_m =1','Location','northwest')
end

% close 2 3 4 5 6 7 8 9 
else
    
    
    [X,Y]=meshgrid(t/3600,eps:1/100:1);

for i=1:length(Cm)
    Z(:,i)=wM(:,i);
    Y(:,i)=Y(:,i)*lm(i);
    
    
end

if app.TwoD.Value==false

s = surf(X,Y,Z,'FaceAlpha',0.9)
s.EdgeColor = 'none';

else
    plot(X(1,:),Z(1,:),'r','Linewidth',2)

end

if app.Comparador.Value==true
    
    dataToCompare=importdata('AperturaFisura.mat')
    dataToCompare(:,1)=dataToCompare(:,1)*((max(max(X)))/(max(dataToCompare(:,1))));
    hold on
    if app.TwoD.Value==false
    plot3(dataToCompare(:,1),dataToCompare(:,1)*0,dataToCompare(:,2),'b','Linewidth',2)
    plot3(X(1,:),Y(1,:),Z(1,:),'r','Linewidth',2)
%     xlim([min(X(1,:)) 1.1*max(X(1,:))])
end


set(gcf,'color','w');

set(gca, 'FontSize' , 20)
% 
% set(gca, 'XScale', 'log')
% 
% set(gca, 'YScale', 'log')
% 
% ylim([1e-3 max(max(lm))])

zlim([0 max([max(Z(1,:))  ])])

grid on

xlabel('\it\fontname{Times New Roman} t [h]','fontsize',23)

ylabel('\it\fontname{Times New Roman} l [m]','fontsize',23)

zlabel('\it\fontname{Times New Roman} \omega [m]','fontsize',23)

title(' Fracture aperture','fontsize',23)

end


        end

        % MultiplePlots value changed function
        function MultiplePlotsValueChanged(app)
            value = app.MultiplePlots.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'KDGApp';
            setAutoResize(app, app.UIFigure, true)
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Units = 'pixels';
            app.StartKGD = uibutton(app.Panel, 'push');
            app.StartKGD.ButtonPushedFcn = createCallbackFcn(app, @StartKGDButtonPushed);
            app.StartKGD.Text = 'Start KGD model';
            app.MultiplePlots = uibutton(app.Panel, 'state');
            app.MultiplePlots.ValueChangedFcn = createCallbackFcn(app, @MultiplePlotsValueChanged);
            app.MultiplePlots.Text = 'Multiple Plots';
            app.Comparador = uibutton(app.Panel, 'state');
            app.Comparador.Text = 'Compare Analytical/FEA';
            app.Label6 = uilabel(app.Panel);
            app.Label6.Text = 'KGD APP            ITBA MC';
            app.LabelKnob = uilabel(app.Panel);
            app.LabelKnob.Text = 'Log(T/T*)';
            app.Time = uiknob(app.Panel, 'continuous');
            app.Time.Limits = [-5 4];
            app.Time.MajorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4];
            app.Time.Value = 3;
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Q0 [(m^2)/s]';
            app.Q0 = uieditfield(app.Panel, 'numeric');
            app.Q0.Limits = [0 Inf];
            app.Q0.Value = 1e-08;
            app.LabelNumericEditField2 = uilabel(app.Panel);
            app.LabelNumericEditField2.Text = 'E  [Pa]';
            app.E = uieditfield(app.Panel, 'numeric');
            app.E.Limits = [0 Inf];
            app.E.Value = 10000000000;
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'KIc  [Pa]';
            app.KIc = uieditfield(app.Panel, 'numeric');
            app.KIc.Limits = [0 Inf];
            app.KIc.Value = 1000000;
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = 'mu [Pa s]';
            app.mu = uieditfield(app.Panel, 'numeric');
            app.mu.Limits = [0 Inf];
            app.mu.Value = 0.0013;
            app.Label3 = uilabel(app.Panel);
            app.Label3.Text = 'v';
            app.v = uieditfield(app.Panel, 'numeric');
            app.v.Limits = [0 1];
            app.v.Value = 0.23;
            app.Label4 = uilabel(app.Panel);
            app.Label4.Text = 'Cl [m/(s^0.5)]';
            app.Cl = uieditfield(app.Panel, 'numeric');
            app.Cl.Limits = [0 Inf];
            app.Cl.Value = 3e-08;
            app.LabelDiscreteKnob = uilabel(app.Panel);
            app.LabelDiscreteKnob.Text = 'Model';
            app.Modelo = uiknob(app.Panel, 'discrete');
            app.Modelo.Items = {'   M', 'Mbow', 'Kbow', '   K'};
            app.Modelo.Value = '   M';
            app.TwoD = uibutton(app.Panel, 'state');
            app.TwoD.Text = '2D View';
        end
    end

    methods (Access = public)

        % Construct app
        function app = KGDApp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end